classdef EEGAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StatusPanel                     matlab.ui.container.Panel
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        DatasetInfoPanel                matlab.ui.container.Panel
        TimeRangeValueLabel             matlab.ui.control.Label
        TimeRangeLabel                  matlab.ui.control.Label
        SamplingRateValueLabel          matlab.ui.control.Label
        SamplingRateLabel               matlab.ui.control.Label
        TrialsValueLabel                matlab.ui.control.Label
        ChannelsValueLabel              matlab.ui.control.Label
        FileNameValueLabel              matlab.ui.control.Label
        TrialsLabel                     matlab.ui.control.Label
        ChannelLabel                    matlab.ui.control.Label
        FileNameLabel                   matlab.ui.control.Label
        RawEEGSignalPanel               matlab.ui.container.Panel
        RawEEGAxes                      matlab.ui.control.UIAxes
        DataControlsPanel               matlab.ui.container.Panel
        PlotEEGButton                   matlab.ui.control.StateButton
        TrialSpinner                    matlab.ui.control.Spinner
        TrialSpinnerLabel               matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        FilteringPhaseTab               matlab.ui.container.Tab
        HilbertResultsPanel             matlab.ui.container.Panel
        InstantaneousFrequencyAxes      matlab.ui.control.UIAxes
        AnalyticSignalAxes              matlab.ui.control.UIAxes
        HilbertPhaseAxes                matlab.ui.control.UIAxes
        HilbertAmplitudeAxes            matlab.ui.control.UIAxes
        FilteredSignalPanel             matlab.ui.container.Panel
        FilteredEEGAxes                 matlab.ui.control.UIAxes
        BandpassFilterSettingsPanel     matlab.ui.container.Panel
        ApplyHilbertButton              matlab.ui.control.Button
        ApplyFilterButton               matlab.ui.control.Button
        FilterTypeDropDown              matlab.ui.control.DropDown
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterOrderField                matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        TransitionWidthField            matlab.ui.control.NumericEditField
        TransitionWidthEditFieldLabel   matlab.ui.control.Label
        UpperFrequencyField             matlab.ui.control.NumericEditField
        UpperCutoffHzEditFieldLabel     matlab.ui.control.Label
        LowerFrequencyField             matlab.ui.control.NumericEditField
        LowerCutoffHzEditFieldLabel     matlab.ui.control.Label
        STFTAnalysisTab                 matlab.ui.container.Tab
        WindowComparisonPanel           matlab.ui.container.Panel
        CompareWindowsButton            matlab.ui.control.Button
        WindowComparisonAxes            matlab.ui.control.UIAxes
        STFTResultsPanel                matlab.ui.container.Panel
        STFTTimeFrequencyAxes           matlab.ui.control.UIAxes
        STFTSpectrumAxes                matlab.ui.control.UIAxes
        STFTSettingsPanel               matlab.ui.container.Panel
        RunSTFTButton                   matlab.ui.control.Button
        STFTMaxFrequencyField           matlab.ui.control.NumericEditField
        MaxFrequencyHzEditFieldLabel    matlab.ui.control.Label
        STFTMinFrequencyField           matlab.ui.control.NumericEditField
        MinFrequencyHzEditFieldLabel    matlab.ui.control.Label
        STFTOverlapField                matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        STFTWindowLengthField           matlab.ui.control.NumericEditField
        WindowlengthmsEditFieldLabel    matlab.ui.control.Label
        STFTWindowDropDown              matlab.ui.control.DropDown
        WindowTypeDropDownLabel         matlab.ui.control.Label
        MultitaperAnalysisTab           matlab.ui.container.Tab
        MultitaperResultsPanel          matlab.ui.container.Panel
        MultitaperTimeFrequencyAxes     matlab.ui.control.UIAxes
        MultitaperSpectrumAxes          matlab.ui.control.UIAxes
        DPSSTapersAxes                  matlab.ui.control.UIAxes
        MultitaperSettingsPanel         matlab.ui.container.Panel
        RunMultitaperButton             matlab.ui.control.Button
        MTMaxFrequencyField             matlab.ui.control.NumericEditField
        MaxFrequencyHzEditFieldLabel_2  matlab.ui.control.Label
        MTMinFrequencyField             matlab.ui.control.NumericEditField
        MinFrequencyHzEditFieldLabel_2  matlab.ui.control.Label
        MTOverlapField                  matlab.ui.control.NumericEditField
        OverlapEditFieldLabel_2         matlab.ui.control.Label
        NumberTapersField               matlab.ui.control.NumericEditField
        NumberofTapersEditFieldLabel    matlab.ui.control.Label
        TimeBandwidthField              matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        MTWindowLengthField             matlab.ui.control.NumericEditField
        WindowLengthmsEditFieldLabel    matlab.ui.control.Label
        ComparisonTab                   matlab.ui.container.Tab
        ComparisonSummaryPanel          matlab.ui.container.Panel
        ComparisonTable                 matlab.ui.control.Table
        ComparisonPlotsPanel            matlab.ui.container.Panel
        SpectralComparisonAxes          matlab.ui.control.UIAxes
        MultitaperComparisonAxes        matlab.ui.control.UIAxes
        STFTComparisonAxes              matlab.ui.control.UIAxes
        HilbertComparisonAxes           matlab.ui.control.UIAxes
        ComparisonSettingsPanel         matlab.ui.container.Panel
        CompareMethodsButton            matlab.ui.control.Button
        ComparisonMetricDropDown        matlab.ui.control.DropDown
        ComparisonMetricDropDownLabel   matlab.ui.control.Label
        ResultExportTab                 matlab.ui.container.Tab
        ExportOptionsPanel              matlab.ui.container.Panel
        UpdateResultsButton             matlab.ui.control.Button
        ResultsTablePanel               matlab.ui.container.Panel
        ResultsSummaryTable             matlab.ui.control.Table
        AnalysisSummaryPanel            matlab.ui.container.Panel
        TaperCardPanel                  matlab.ui.container.Panel
        ResultTapersValueLabel          matlab.ui.control.Label
        NumberofTapersLabel             matlab.ui.control.Label
        SamplingRateCardPanel           matlab.ui.container.Panel
        ResultSamplingRateValueLabel    matlab.ui.control.Label
        SamplingRateLabel_2             matlab.ui.control.Label
        NWCardPanel                     matlab.ui.container.Panel
        ResultNWValueLabel              matlab.ui.control.Label
        MultitaperNWLabel               matlab.ui.control.Label
        STFTWindowCardPanel             matlab.ui.container.Panel
        ResultSTFTWindowValueLabel      matlab.ui.control.Label
        STFTWindowLabel                 matlab.ui.control.Label
        TrialCardPanel                  matlab.ui.container.Panel
        ResultTrialValueLabel           matlab.ui.control.Label
        SelectedTrialLabel              matlab.ui.control.Label
        FilterBandCardPanel             matlab.ui.container.Panel
        ResultFilterBandValueLabel      matlab.ui.control.Label
        FilterBandLabel                 matlab.ui.control.Label
        ChannelCardPanel                matlab.ui.container.Panel
        SelectedChannelLabel            matlab.ui.control.Label
        ResultChannelValueLabel         matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        HelpPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        HelpTextArea                    matlab.ui.control.TextArea
        ToolbarPanel                    matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        ExportResultButton              matlab.ui.control.Button
        SaveResultButton                matlab.ui.control.Button
        LoadEEGButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        EEG
        FilteredEEG
        AnalyticEEG

        STFTPower
        STFTTimes
        STFTFrequencies

        MultitaperPower
        MultitaperTimes
        MultitaperFrequencies

        ComparisonPeakFrequency
        ComparisonPower

        HilbertComputationTime
        STFTComputationTime
        MultitaperComputationTime

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadEEGButton
        function LoadEEGButtonPushed(app, event)

            app.StatusLabel.Text = 'Load EEG button pressed';
            
            [file, path] = uigetfile('*.mat', 'Select EEG Dataset');

            if isequal(file, 0)
                return
            end

            data = load(fullfile(path, file));

            app.EEG = data.EEG;

            % Display dataset information
            app.FileNameValueLabel.Text = file;

            app.ChannelsValueLabel.Text = num2str(app.EEG.nbchan);

            app.TrialsValueLabel.Text = num2str(app.EEG.trials);

            app.SamplingRateValueLabel.Text =[num2str(app.EEG.srate) ' Hz'];

            app.TimeRangeValueLabel.Text =[num2str(app.EEG.times(1)) ' to ' num2str(app.EEG.times(end)) ' ms'];

            % Update trial selector
            app.TrialSpinner.Limits = [1 app.EEG.trials];
            app.TrialSpinner.Value = 1;

            % Update channel selector
            app.ChannelDropDown.Items = {app.EEG.chanlocs.labels};

            % Update status
            app.StatusLabel.Text =['Status: Loaded ' file];

            
        end

        % Value changed function: PlotEEGButton
        function PlotEEGButtonValueChanged(app, event)

               % Check that EEG data has been loaded
                if isempty(app.EEG)
                    uialert(app.UIFigure,'Please load an EEG dataset first.', 'No EEG Data');
                    return
                end

                % Get selected channel name
                selectedChannel = app.ChannelDropDown.Value;

                % Find channel index
                chanidx = strcmpi(selectedChannel,{app.EEG.chanlocs.labels});

                % Get selected trial
                trial = round(app.TrialSpinner.Value);

                % Extract EEG data
                eegSignal = squeeze(app.EEG.data(chanidx,:,trial));

                % Plot EEG
                plot(app.RawEEGAxes, app.EEG.times, eegSignal);

                % Labels
                xlabel(app.RawEEGAxes,'Time (ms)');
                ylabel(app.RawEEGAxes,'Amplitude (\muV)');

                % Title
                title(app.RawEEGAxes, ['Raw EEG - Channel ' selectedChannel ', Trial ' num2str(trial)]);

                % Fit axes to data
                axis(app.RawEEGAxes,'tight');

                % Update status
                app.StatusLabel.Text = ['Status: Displaying channel ' selectedChannel ', trial ' num2str(trial)];

        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
    
           % Check EEG has been loaded
           if isempty(app.EEG)
               uialert(app.UIFigure,'Please load an EEG dataset first.','No EEG Data');
        return
    end

        % Read filter settings
        lowerFreq = app.LowerFrequencyField.Value;
        upperFreq = app.UpperFrequencyField.Value;
        transitionWidth = app.TransitionWidthField.Value;
        filterOrder = round(app.FilterOrderField.Value);
        filterType = app.FilterTypeDropDown.Value;

        % Selected channel and trial
        selectedChannel = app.ChannelDropDown.Value;
        trial = round(app.TrialSpinner.Value);

        chanidx = strcmpi(selectedChannel, {app.EEG.chanlocs.labels});

        % Extract EEG signal
        rawSignal = squeeze(double(app.EEG.data(chanidx,:,trial)) );

        % Nyquist frequency
        nyquist = app.EEG.srate/2;

        % Check frequency settings
        if lowerFreq <= 0 || upperFreq >= nyquist || lowerFreq >= upperFreq

           uialert(app.UIFigure,'Please enter valid lower and upper cutoff frequencies.', 'Invalid Filter Settings');
            return
        end

        % Select filter type
        if strcmp(filterType,'FIR')

        % FIR filter design
        ffrequencies = [0 (1-transitionWidth)*lowerFreq lowerFreq upperFreq ...
            (1+transitionWidth)*upperFreq nyquist ] / nyquist;

        idealresponse = [0 0 1 1 0 0];

        filterweights = firls( filterOrder, ffrequencies, idealresponse);

        % Zero-phase filtering
        app.FilteredEEG = filtfilt( filterweights, 1, rawSignal);

       else

        % Butterworth bandpass filter
        [b,a] = butter(5, [lowerFreq upperFreq]/nyquist, 'bandpass');

        app.FilteredEEG = filtfilt(b, a, rawSignal);

       end

    % Plot filtered signal
    plot(app.FilteredEEGAxes, app.EEG.times, app.FilteredEEG);

    xlabel(app.FilteredEEGAxes,'Time (ms)');
    ylabel(app.FilteredEEGAxes,'Amplitude (\muV)');

    title(app.FilteredEEGAxes, ...
        sprintf('%g-%g Hz %s Filtered EEG - %s, Trial %d', lowerFreq, ...
        upperFreq, filterType, selectedChannel, trial));

    axis(app.FilteredEEGAxes,'tight');

    % Update status
    app.StatusLabel.Text = sprintf('Status: %g-%g Hz %s filtering completed.', lowerFreq, upperFreq, filterType);

        end

        % Button pushed function: ApplyHilbertButton
        function ApplyHilbertButtonPushed(app, event)

                % Check filtered EEG exists
                if isempty(app.FilteredEEG)
                    uialert(app.UIFigure, ...
                        'Please apply the bandpass filter first.', 'No Filtered Signal');
                    return
                end

                % Start computation timer
                tic;

                % Hilbert transform
                app.AnalyticEEG = hilbert(app.FilteredEEG);


                % Extract instantaneous amplitude and phase
                instantaneousAmplitude = abs(app.AnalyticEEG);
                instantaneousPhase = angle(app.AnalyticEEG);

                % Instantaneous frequency

                unwrappedPhase = unwrap(instantaneousPhase);

                instantaneousFrequency = app.EEG.srate/(2*pi) * diff(unwrappedPhase);

                % Time vector for instantaneous frequency
                frequencyTime = app.EEG.times(1:end-1);

                % Plot instantaneous frequency
                plot(app.InstantaneousFrequencyAxes, frequencyTime, instantaneousFrequency);

                xlabel(app.InstantaneousFrequencyAxes,'Time (ms)');
                ylabel(app.InstantaneousFrequencyAxes,'Frequency (Hz)');
                title(app.InstantaneousFrequencyAxes,'Instantaneous Frequency');
                axis(app.InstantaneousFrequencyAxes,'tight');

                % Plot instantaneous amplitude
                plot(app.HilbertAmplitudeAxes, app.EEG.times, instantaneousAmplitude);

                xlabel(app.HilbertAmplitudeAxes,'Time (ms)');
                ylabel(app.HilbertAmplitudeAxes,'Amplitude (\muV)');
                title(app.HilbertAmplitudeAxes,'Instantaneous Amplitude');
                axis(app.HilbertAmplitudeAxes,'tight');

                % Plot instantaneous phase
                plot(app.HilbertPhaseAxes, app.EEG.times, instantaneousPhase);

                xlabel(app.HilbertPhaseAxes,'Time (ms)');
                ylabel(app.HilbertPhaseAxes,'Phase (rad)');
                title(app.HilbertPhaseAxes,'Instantaneous Phase');
                axis(app.HilbertPhaseAxes,'tight');

                % Plot real and imaginary components
                plot(app.AnalyticSignalAxes, app.EEG.times, real(app.AnalyticEEG));

                hold(app.AnalyticSignalAxes,'on');

                plot(app.AnalyticSignalAxes, app.EEG.times, imag(app.AnalyticEEG));

                hold(app.AnalyticSignalAxes,'off');

                xlabel(app.AnalyticSignalAxes,'Time (ms)');
                ylabel(app.AnalyticSignalAxes,'Amplitude (\muV)');
                title(app.AnalyticSignalAxes,'Analytic Signal');
                axis(app.AnalyticSignalAxes,'tight');

                legend(app.AnalyticSignalAxes,'Real Part', 'Imaginary Part');

                % Store computation time
                app.HilbertComputationTime = toc;

                % Update application status
                app.StatusLabel.Text = ...
                    sprintf('Status: Hilbert transform completed in %.4f seconds.', app.HilbertComputationTime);

           
        end

        % Button pushed function: RunSTFTButton
        function RunSTFTButtonPushed(app, event)
          
               % Check EEG data
               if isempty(app.EEG)
                 uialert(app.UIFigure,'Please load an EEG dataset first.','No EEG Data');
                 return
               end

               % Start computation timer
               tic;

               % Selected channel and trial
               selectedChannel = app.ChannelDropDown.Value;
               trial = round(app.TrialSpinner.Value);

               chanidx = strcmpi(selectedChannel, {app.EEG.chanlocs.labels});

               eegSignal = squeeze(double(app.EEG.data(chanidx,:,trial)) );

               % Sampling rate
               srate = app.EEG.srate;

               % STFT settings
               windowType = app.STFTWindowDropDown.Value;
               windowLengthMS = app.STFTWindowLengthField.Value;
               overlapPercent = app.STFTOverlapField.Value;
               minFreq = app.STFTMinFrequencyField.Value;
               maxFreq = app.STFTMaxFrequencyField.Value;

               % Convert window length to samples
               windowSamples = round(windowLengthMS/1000 * srate );

               % Calculate overlap
               overlapSamples = round(windowSamples * overlapPercent/100 );

               if overlapSamples >= windowSamples
                  uialert(app.UIFigure,'Overlap must be less than 100%.', 'Invalid STFT Settings');
                   return
               end

              % Create taper/window
              switch windowType

              case 'Hann'
                   window = hann(windowSamples);

              case 'Hamming'
                   window = hamming(windowSamples);

              case 'Gaussian'
                    window = gausswin(windowSamples);

               end

              % FFT length
              nfft = max(256, 2^nextpow2(windowSamples));

              % Short-time Fourier transform
              [S,F,T] = spectrogram(eegSignal, window, overlapSamples, nfft, srate);

              % Convert to power
              powerSTFT = abs(S).^2;

              % Select frequency range
              freqidx = F >= minFreq & F <= maxFreq;

              F = F(freqidx);
              powerSTFT = powerSTFT(freqidx,:);

             % Convert time to ms and align with EEG epoch
             Tms = T*1000 + app.EEG.times(1);
        
             % Store results
             app.STFTPower = powerSTFT;
             app.STFTTimes = Tms;
             app.STFTFrequencies = F;

             % Mean power spectrum
             meanPower = mean(powerSTFT,2);
        
             plot(app.STFTSpectrumAxes, F, meanPower);
        
             xlabel(app.STFTSpectrumAxes,'Frequency (Hz)');
             ylabel(app.STFTSpectrumAxes,'Power');
             title(app.STFTSpectrumAxes,'Power Spectrum');
        
             xlim(app.STFTSpectrumAxes, [minFreq maxFreq]);
        
             grid(app.STFTSpectrumAxes,'on');
        
             % Time-frequency map
             imagesc(app.STFTTimeFrequencyAxes, Tms, F, 10*log10(powerSTFT + eps));
        
             axis(app.STFTTimeFrequencyAxes,'xy');
        
             xlabel(app.STFTTimeFrequencyAxes,'Time (ms)');
             ylabel(app.STFTTimeFrequencyAxes,'Frequency (Hz)');
             title(app.STFTTimeFrequencyAxes, 'STFT Time-Frequency Power');
        
             colorbar(app.STFTTimeFrequencyAxes);

             % Store STFT computation time
             app.STFTComputationTime = toc;
        
             app.StatusLabel.Text = sprintf('Status: STFT completed in %.4f seconds.', app.STFTComputationTime);

        end

        % Button pushed function: CompareWindowsButton
        function CompareWindowsButtonPushed(app, event)
            % Check EEG is loaded
            if isempty(app.EEG)
                uialert(app.UIFigure, 'Please load an EEG dataset first.', 'No EEG Data');
                return
            end

            % Get window length
            windowLengthMS = app.STFTWindowLengthField.Value;

            windowSamples = round(windowLengthMS/1000 * app.EEG.srate);

            % Create windows
            hannWindow = hann(windowSamples);
            hammingWindow = hamming(windowSamples);
            gaussianWindow = gausswin(windowSamples);

            % Clear axes
            cla(app.WindowComparisonAxes);

            % Plot Hann
            plot(app.WindowComparisonAxes, hannWindow);
            hold(app.WindowComparisonAxes,'on');

            % Plot Hamming
            plot(app.WindowComparisonAxes, hammingWindow);

            % Plot Gaussian
            plot(app.WindowComparisonAxes, gaussianWindow);

            hold(app.WindowComparisonAxes,'off');

            % Labels
            xlabel(app.WindowComparisonAxes,'Samples');
            ylabel(app.WindowComparisonAxes,'Amplitude');

            title(app.WindowComparisonAxes, 'Window Function Comparison');

            legend(app.WindowComparisonAxes, 'Hann', 'Hamming', 'Gaussian');

            grid(app.WindowComparisonAxes,'on');

            % Status
            app.StatusLabel.Text = 'Status: Window comparison displayed.';
        end

        % Button pushed function: RunMultitaperButton
        function RunMultitaperButtonPushed(app, event)
            % Check EEG has been loaded
            if isempty(app.EEG)
                uialert(app.UIFigure, 'Please load an EEG dataset first.', 'No EEG Data');
                return
            end

            % Start computation timer
            tic;

            % Get selected channel and trial
            selectedChannel = app.ChannelDropDown.Value;
            trial = round(app.TrialSpinner.Value);

            chanidx = strcmpi(selectedChannel, {app.EEG.chanlocs.labels});

            % Extract EEG signal
            eegSignal = squeeze( double(app.EEG.data(chanidx,:,trial)) );

            % Sampling rate
            srate = app.EEG.srate;

            % Read multitaper settings
            windowLengthMS = app.MTWindowLengthField.Value;
            NW = app.TimeBandwidthField.Value;
            K = round(app.NumberTapersField.Value);
            overlapPercent = app.MTOverlapField.Value;
            minFreq = app.MTMinFrequencyField.Value;
            maxFreq = app.MTMaxFrequencyField.Value;

            windowSamples = round(windowLengthMS/1000 * srate);

            overlapSamples = round(windowSamples * overlapPercent/100);

            stepSamples = windowSamples - overlapSamples;

            if stepSamples <= 0
                uialert(app.UIFigure, 'Overlap must be less than 100%.', 'Invalid Multitaper Settings');
                return
            end

            % Create DPSS tapers
            [tapers,~] = dpss(windowSamples, NW, K);

            % windowSamples = number of samples in each segment
            % NW = time-bandwidth product
            % K  = number of DPSS tapers

            % FFT Length
            nfft = max(256, 2^nextpow2(windowSamples));

            % Frequency Vector
            frequencies = (0:nfft/2) * srate/nfft;

            % Select Frequency range
            freqidx = frequencies >= minFreq & frequencies <= maxFreq;

            % Extract Frequencies within the selected range
            selectedFreqs = frequencies(freqidx);

            % Determine the starting sample of each overlapping time window
            windowStarts = 1:stepSamples:(length(eegSignal)-windowSamples+1);

            % Calculate the total number of time windows
            numWindows = length(windowStarts);

            % Preallocate matrix to store multitaper time-frequency power
            multitaperTF = zeros(length(selectedFreqs), numWindows);

            for win = 1:numWindows

                % Extract current EEG segment
                idx = windowStarts(win): windowStarts(win)+windowSamples-1;

                segment = eegSignal(idx);

                % Store power from each taper
                taperPower = zeros(length(selectedFreqs), K);

                for taperIdx = 1:K

                    % Apply DPSS taper
                    taperedSignal = segment(:) .* tapers(:,taperIdx);

                    % FFT
                    fftResult = fft(taperedSignal, nfft);

                    % Power spectrum
                    powerSpectrum = abs(fftResult(1:nfft/2+1)).^2;

                    % Store selected frequencies
                    taperPower(:,taperIdx) = powerSpectrum(freqidx);

                end

                % Average across DPSS tapers
                multitaperTF(:,win) = mean(taperPower,2);

            end

            % Calculate the centre sample of each time window
            windowCenters = windowStarts + windowSamples/2;

            % Convert window centre positions to EEG time in milliseconds
            multitaperTimes = app.EEG.times(1) + (windowCenters-1)/srate*1000;
            
            % Store multitaper results for later analysis and comparison
            app.MultitaperPower = multitaperTF;
            app.MultitaperTimes = multitaperTimes;
            app.MultitaperFrequencies = selectedFreqs;
            
            % Clear any previous DPSS taper plot
            cla(app.DPSSTapersAxes);

            % Hold axes so each taper can be plotted separately
            hold(app.DPSSTapersAxes,'on');

            % Plot each DPSS taper with a legend label
            for taperIdx = 1:K

                plot(app.DPSSTapersAxes, ...
                    tapers(:,taperIdx), ...
                    'LineWidth',1.2, ...
                    'DisplayName',sprintf('Taper %d',taperIdx));

            end

            hold(app.DPSSTapersAxes,'off');

            % Label the DPSS taper plot
            xlabel(app.DPSSTapersAxes,'Samples');
            ylabel(app.DPSSTapersAxes,'Amplitude');

            title(app.DPSSTapersAxes, ...
                sprintf('DPSS Tapers (NW = %g, K = %d)',NW,K));

            % Display grid
            grid(app.DPSSTapersAxes,'on');

            % Display legend/key for individual tapers
            legend(app.DPSSTapersAxes, ...
                'show', ...
                'Location','northeast');
            
            % Calculate the average multitaper power across all time windows
            meanMultitaperPower = mean(multitaperTF,2);
            
            % Plot the average multitaper power spectrum
            plot(app.MultitaperSpectrumAxes, selectedFreqs, meanMultitaperPower);
            
            % Label the multitaper power spectrum
            xlabel(app.MultitaperSpectrumAxes,'Frequency (Hz)');
            ylabel(app.MultitaperSpectrumAxes,'Power');
            
            title(app.MultitaperSpectrumAxes, 'Multitaper Power Spectrum');
            
            % Limit the x-axis to the selected frequency range
            xlim(app.MultitaperSpectrumAxes, [minFreq maxFreq]);
            
            % Display grid on the power spectrum
            grid(app.MultitaperSpectrumAxes,'on');
            
            
            % Convert multitaper power to decibels and display
            % the time-frequency representation
            imagesc(app.MultitaperTimeFrequencyAxes, multitaperTimes, selectedFreqs, 10*log10(multitaperTF + eps));
            
            % Set frequency to increase upwards on the y-axis
            axis(app.MultitaperTimeFrequencyAxes,'xy');
            
            % Label the multitaper time-frequency plot
            xlabel(app.MultitaperTimeFrequencyAxes,'Time (ms)');
            ylabel(app.MultitaperTimeFrequencyAxes,'Frequency (Hz)');
            
            title(app.MultitaperTimeFrequencyAxes,'Multitaper Time-Frequency Power');
            
            % Add a color bar to represent power magnitude
            colorbar(app.MultitaperTimeFrequencyAxes);

            % Store Multitaper computation time
            app.MultitaperComputationTime = toc;
            
            app.StatusLabel.Text = sprintf('Status: Multitaper analysis completed in %.4f seconds.', app.MultitaperComputationTime);

        end

        % Button pushed function: CompareMethodsButton
        function CompareMethodsButtonPushed(app, event)
            % Check that all required analyses have been completed
            if isempty(app.AnalyticEEG) || isempty(app.STFTPower) || isempty(app.MultitaperPower)

                uialert(app.UIFigure,'Please run the Hilbert, STFT and Multitaper analyses first.', 'Missing Analysis Results');

                return
            end

            % Calculate instantaneous amplitude from the analytic signal
            hilbertAmplitude = abs(app.AnalyticEEG);

            % Plot Filter-Hilbert amplitude
            plot(app.HilbertComparisonAxes, app.EEG.times, hilbertAmplitude);

            xlabel(app.HilbertComparisonAxes,'Time (ms)');
            ylabel(app.HilbertComparisonAxes,'Amplitude (\muV)');
            title(app.HilbertComparisonAxes, 'Filter-Hilbert Instantaneous Amplitude');

            axis(app.HilbertComparisonAxes,'tight');
            grid(app.HilbertComparisonAxes,'on');


            % Calculate mean STFT power across time
            meanSTFTPower = mean(app.STFTPower,2);

            % Find the STFT peak frequency
            [~, stftPeakIdx] = max(meanSTFTPower);

            stftPeakFrequency = app.STFTFrequencies(stftPeakIdx);

            % Plot STFT mean power spectrum
            plot(app.STFTComparisonAxes, app.STFTFrequencies, meanSTFTPower);

            xlabel(app.STFTComparisonAxes,'Frequency (Hz)');
            ylabel(app.STFTComparisonAxes,'Power');
            title(app.STFTComparisonAxes, 'STFT Mean Power Spectrum');

            grid(app.STFTComparisonAxes,'on');


            % Calculate mean Multitaper power across time
            meanMTPower = mean(app.MultitaperPower,2);

            % Find the Multitaper peak frequency
            [~, mtPeakIdx] = max(meanMTPower);

            mtPeakFrequency = app.MultitaperFrequencies(mtPeakIdx);

            % Plot Multitaper mean power spectrum
            plot(app.MultitaperComparisonAxes, app.MultitaperFrequencies, meanMTPower);

            xlabel(app.MultitaperComparisonAxes,'Frequency (Hz)');
            ylabel(app.MultitaperComparisonAxes,'Power');
            title(app.MultitaperComparisonAxes, 'Multitaper Mean Power Spectrum');

            grid(app.MultitaperComparisonAxes,'on');


            % Normalize STFT spectrum
            normalizedSTFT = meanSTFTPower ./ max(meanSTFTPower);

            % Normalize Multitaper spectrum
            normalizedMT = meanMTPower ./ max(meanMTPower);

            % Clear previous spectral comparison
            cla(app.SpectralComparisonAxes);

            % Plot STFT spectrum
            plot(app.SpectralComparisonAxes, app.STFTFrequencies, normalizedSTFT);

            hold(app.SpectralComparisonAxes,'on');

            % Plot Multitaper spectrum
            plot(app.SpectralComparisonAxes, app.MultitaperFrequencies, normalizedMT);

            hold(app.SpectralComparisonAxes,'off');

            % Label comparison plot
            xlabel(app.SpectralComparisonAxes,'Frequency (Hz)');
            ylabel(app.SpectralComparisonAxes,'Normalized Power');

            title(app.SpectralComparisonAxes,'STFT vs Multitaper Spectrum');

            legend(app.SpectralComparisonAxes,'STFT', 'Multitaper');

            grid(app.SpectralComparisonAxes,'on');

            % Create comparison summary table
            comparisonData = {
                'Filter-Hilbert', 'Instantaneous Amplitude', NaN, app.HilbertComputationTime;

                'STFT', 'Mean Spectral Power', stftPeakFrequency, app.STFTComputationTime;

                'Multitaper', 'Mean Spectral Power', mtPeakFrequency, app.MultitaperComputationTime
                 };

            app.ComparisonTable.Data = comparisonData;

            app.ComparisonTable.ColumnName = {
                'Method','Main Output','Peak Frequency (Hz)','Comparison Time (s)'
                };

            % Update application status
            app.StatusLabel.Text = 'Status: Method comparison completed.';

        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            % Check that EEG data has been loaded
            if isempty(app.EEG)
                uialert(app.UIFigure, 'Please load and analyse EEG data first.', 'No Results');
                return
            end

            % Asks user where to save the results
            [file, path] = uiputfile( '*.mat', 'Save EEG Analysis Results', 'EEG_Analysis_Results.mat');

            % Stops if user cancels
            if isequal(file,0)
                return
            end

            % Create results structure
            Results = struct;

            % Store original EEG information
            Results.Channel = app.ChannelDropDown.Value;
            Results.Trial = app.TrialSpinner.Value;
            Results.SamplingRate = app.EEG.srate;
            Results.Time = app.EEG.times;

            % Store Filter-Hilbert results
            Results.FilteredEEG = app.FilteredEEG;
            Results.AnalyticEEG = app.AnalyticEEG;

            % Store STFT results
            Results.STFTPower = app.STFTPower;
            Results.STFTTimes = app.STFTTimes;
            Results.STFTFrequencies = app.STFTFrequencies;

            % Store Multitaper results
            Results.MultitaperPower = app.MultitaperPower;
            Results.MultitaperTimes = app.MultitaperTimes;
            Results.MultitaperFrequencies = app.MultitaperFrequencies;

            % Store computation times
            Results.HilbertComputationTime = app.HilbertComputationTime;

            Results.STFTComputationTime = app.STFTComputationTime;

            Results.MultitaperComputationTime = app.MultitaperComputationTime;

            % Save results to MAT file
            save(fullfile(path,file), 'Results');

            % Update status
            app.StatusLabel.Text = ['Status: Results saved as ' file];

        end

        % Button pushed function: ExportResultButton
        function ExportResultButtonPushed(app, event)
            % Ask user where to save exported figure
            [file, path] = uiputfile( ...
                {'*.png'; '*.jpg'; '*.pdf'}, 'Export Analysis Figure');

            % Stop if user cancels
            if isequal(file,0)
                return
            end

            % Get selected file extension
            [~,~,ext] = fileparts(file);

            % Export the entire application window
            switch lower(ext)

                case '.png'
                    exportapp(app.UIFigure, fullfile(path,file));

                case '.jpg'
                    exportapp(app.UIFigure, fullfile(path,file));

                case '.pdf'
                    exportapp(app.UIFigure, fullfile(path,file));

                otherwise
                    uialert(app.UIFigure,'Please choose PNG, JPG or PDF.', 'Invalid File Type');
                    return
            end

            % Update status
            app.StatusLabel.Text = ['Status: Figure exported as ' file];
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Clear loaded and processed EEG data
            app.EEG = [];
            app.FilteredEEG = [];
            app.AnalyticEEG = [];

            % Clear STFT results
            app.STFTPower = [];
            app.STFTTimes = [];
            app.STFTFrequencies = [];

            % Clear Multitaper results
            app.MultitaperPower = [];
            app.MultitaperTimes = [];
            app.MultitaperFrequencies = [];

            % Clear comparison results
            app.ComparisonPeakFrequency = [];
            app.ComparisonPower = [];

            % Clear computation times
            app.HilbertComputationTime = [];
            app.STFTComputationTime = [];
            app.MultitaperComputationTime = [];


            % Reset dataset information
            app.FileNameValueLabel.Text = '-';
            app.ChannelsValueLabel.Text = '-';
            app.TrialsValueLabel.Text = '-';
            app.SamplingRateValueLabel.Text = '-';
            app.TimeRangeValueLabel.Text = '-';

            % Reset channel and trial controls
            app.ChannelDropDown.Items = {'-'};
            app.ChannelDropDown.Value = '-';

            app.TrialSpinner.Limits = [1 Inf];
            app.TrialSpinner.Value = 1;


            % Clear Home plot
            cla(app.RawEEGAxes);

            % Clear Filter-Hilbert plots
            cla(app.FilteredEEGAxes);
            cla(app.HilbertAmplitudeAxes);
            cla(app.HilbertPhaseAxes);
            cla(app.InstantaneousFrequencyAxes);
            cla(app.AnalyticSignalAxes);

            % Clear STFT plots
            cla(app.STFTSpectrumAxes);
            cla(app.STFTTimeFrequencyAxes);
            cla(app.WindowComparisonAxes);

            % Clear Multitaper plot
            cla(app.DPSSTapersAxes);
            cla(app.MultitaperSpectrumAxes);
            cla(app.MultitaperTimeFrequencyAxes);

            % Clear Comparison plots
            cla(app.HilbertComparisonAxes);
            cla(app.STFTComparisonAxes);
            cla(app.MultitaperComparisonAxes);
            cla(app.SpectralComparisonAxes);

            % Clear comparison table
            app.ComparisonTable.Data = {};


            % Reset filter settings
            app.LowerFrequencyField.Value = 4;
            app.UpperFrequencyField.Value = 10;
            app.TransitionWidthField.Value = 0.2;
            app.FilterOrderField.Value = 192;
            app.FilterTypeDropDown.Value = 'FIR';

            % Reset STFT settings
            app.STFTWindowDropDown.Value = 'Hann';
            app.STFTWindowLengthField.Value = 500;
            app.STFTOverlapField.Value = 50;
            app.STFTMinFrequencyField.Value = 1;
            app.STFTMaxFrequencyField.Value = 40;

            % Reset Multitaper settings
            app.MTWindowLengthField.Value = 500;
            app.TimeBandwidthField.Value = 3;
            app.NumberTapersField.Value = 5;
            app.MTOverlapField.Value = 50;
            app.MTMinFrequencyField.Value = 1;
            app.MTMaxFrequencyField.Value = 40;

            % Update application status
            app.StatusLabel.Text = 'Status: Ready - Load an EEG dataset.';

        end

        % Button pushed function: UpdateResultsButton
        function UpdateResultsButtonPushed(app, event)
            
            % Check that EEG data has been loaded
            if isempty(app.EEG)
                uialert(app.UIFigure, 'Please load an EEG dataset first.', 'No EEG Data');
                return
            end

            % Update selected dataset information
            app.ResultChannelValueLabel.Text = app.ChannelDropDown.Value;

            app.ResultTrialValueLabel.Text = num2str(app.TrialSpinner.Value);

            app.ResultSamplingRateValueLabel.Text = [num2str(app.EEG.srate) ' Hz'];

            % Update filter settings
            app.ResultFilterBandValueLabel.Text = sprintf('%g - %g Hz', ...
                app.LowerFrequencyField.Value, app.UpperFrequencyField.Value);

            % Update STFT settings
            app.ResultSTFTWindowValueLabel.Text = sprintf('%g ms (%s)', ...
                app.STFTWindowLengthField.Value, app.STFTWindowDropDown.Value);

            % Update Multitaper settings
            app.ResultNWValueLabel.Text = num2str(app.TimeBandwidthField.Value);

            app.ResultTapersValueLabel.Text = num2str(app.NumberTapersField.Value);

            % Default peak-frequency values
            stftPeakFrequency = NaN;
            mtPeakFrequency = NaN;


            % Calculate STFT peak frequency
            if ~isempty(app.STFTPower)

                meanSTFTPower = mean(app.STFTPower,2);

                [~,stftPeakIdx] = max(meanSTFTPower);

                stftPeakFrequency = app.STFTFrequencies(stftPeakIdx);

            end

            % Calculate Multitaper peak frequency
            if ~isempty(app.MultitaperPower)

                meanMTPower = mean(app.MultitaperPower,2);

                [~,mtPeakIdx] = max(meanMTPower);

                mtPeakFrequency = app.MultitaperFrequencies(mtPeakIdx);

            end


            % Create results summary table
            resultsData = {
                'Filter-Hilbert', NaN, app.HilbertComputationTime;
                'STFT', stftPeakFrequency, app.STFTComputationTime;
                'Multitaper', mtPeakFrequency, app.MultitaperComputationTime
                };

            app.ResultsSummaryTable.Data = resultsData;

            app.ResultsSummaryTable.ColumnName = {
                'Method', 'Peak Frequency (Hz)', 'Computation Time (s)'
                };


            % Update application status
            app.StatusLabel.Text = 'Status: Results summary updated.';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'EEG Signal Processing Simulator';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ToolbarPanel = uipanel(app.GridLayout);
            app.ToolbarPanel.Title = 'ToolbarPanel';
            app.LoadEEGButton = uibutton(app.ToolbarPanel, 'push');
            app.LoadEEGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEEGButtonPushed, true);
            app.LoadEEGButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'loading.png');
            app.LoadEEGButton.Text = 'Load EEG';
            app.SaveResultButton = uibutton(app.ToolbarPanel, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'save.png');
            app.SaveResultButton.Text = 'Save Result';
            app.ExportResultButton = uibutton(app.ToolbarPanel, 'push');
            app.ExportResultButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultButtonPushed, true);
            app.ExportResultButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'export.png');
            app.ExportResultButton.Text = 'Export Result';
            app.ResetButton = uibutton(app.ToolbarPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'arrow (1).png');
            app.ResetButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.DataControlsPanel = uipanel(app.HomeTab);
            app.DataControlsPanel.Title = 'EEG Data Controls';
            app.ChannelDropDownLabel = uilabel(app.DataControlsPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.DataControlsPanel);
            app.ChannelDropDown.Items = {'P7', '01', 'Fz', 'Cz', 'Pz'};
            app.ChannelDropDown.Value = 'P7';
            app.TrialSpinnerLabel = uilabel(app.DataControlsPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.DataControlsPanel);
            app.TrialSpinner.Value = 1;
            app.PlotEEGButton = uibutton(app.DataControlsPanel, 'state');
            app.PlotEEGButton.ValueChangedFcn = createCallbackFcn(app, @PlotEEGButtonValueChanged, true);
            app.PlotEEGButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'line.png');
            app.PlotEEGButton.Text = 'Plot EEG';
            app.RawEEGSignalPanel = uipanel(app.HomeTab);
            app.RawEEGSignalPanel.Title = 'Raw EEG Signal';
            app.RawEEGAxes = uiaxes(app.RawEEGSignalPanel);
            title(app.RawEEGAxes, 'Raw EEG Signal')
            xlabel(app.RawEEGAxes, 'Time (ms)')
            ylabel(app.RawEEGAxes, 'Amplitude (µV)')
            zlabel(app.RawEEGAxes, 'Z')
            app.DatasetInfoPanel = uipanel(app.HomeTab);
            app.DatasetInfoPanel.Title = 'EEG File Information';
            app.FileNameLabel = uilabel(app.DatasetInfoPanel);
            app.FileNameLabel.Text = 'File:';
            app.ChannelLabel = uilabel(app.DatasetInfoPanel);
            app.ChannelLabel.Text = 'Channel:';
            app.TrialsLabel = uilabel(app.DatasetInfoPanel);
            app.TrialsLabel.Text = 'Trials:';
            app.FileNameValueLabel = uilabel(app.DatasetInfoPanel);
            app.FileNameValueLabel.Text = 'No file loaded';
            app.ChannelsValueLabel = uilabel(app.DatasetInfoPanel);
            app.ChannelsValueLabel.Text = '--';
            app.TrialsValueLabel = uilabel(app.DatasetInfoPanel);
            app.TrialsValueLabel.Text = '--';
            app.SamplingRateLabel = uilabel(app.DatasetInfoPanel);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.SamplingRateValueLabel = uilabel(app.DatasetInfoPanel);
            app.SamplingRateValueLabel.Text = '--';
            app.TimeRangeLabel = uilabel(app.DatasetInfoPanel);
            app.TimeRangeLabel.Text = 'Time Range:';
            app.TimeRangeValueLabel = uilabel(app.DatasetInfoPanel);
            app.TimeRangeValueLabel.Text = '--';
            app.FilteringPhaseTab = uitab(app.TabGroup);
            app.FilteringPhaseTab.Title = 'Filtering & Phase';
            app.BandpassFilterSettingsPanel = uipanel(app.FilteringPhaseTab);
            app.BandpassFilterSettingsPanel.Title = 'Bandpass Filter Settings';
            app.LowerCutoffHzEditFieldLabel = uilabel(app.BandpassFilterSettingsPanel);
            app.LowerCutoffHzEditFieldLabel.Text = 'Lower Cutoff (Hz)';
            app.LowerFrequencyField = uieditfield(app.BandpassFilterSettingsPanel, 'numeric');
            app.LowerFrequencyField.Value = 4;
            app.UpperCutoffHzEditFieldLabel = uilabel(app.BandpassFilterSettingsPanel);
            app.UpperCutoffHzEditFieldLabel.Text = 'Upper Cutoff (Hz)';
            app.UpperFrequencyField = uieditfield(app.BandpassFilterSettingsPanel, 'numeric');
            app.UpperFrequencyField.Value = 10;
            app.TransitionWidthEditFieldLabel = uilabel(app.BandpassFilterSettingsPanel);
            app.TransitionWidthEditFieldLabel.Text = 'Transition Width';
            app.TransitionWidthField = uieditfield(app.BandpassFilterSettingsPanel, 'numeric');
            app.TransitionWidthField.Value = 0.2;
            app.FilterOrderEditFieldLabel = uilabel(app.BandpassFilterSettingsPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderField = uieditfield(app.BandpassFilterSettingsPanel, 'numeric');
            app.FilterOrderField.Value = 192;
            app.FilterTypeDropDownLabel = uilabel(app.BandpassFilterSettingsPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.BandpassFilterSettingsPanel);
            app.FilterTypeDropDown.Items = {'FIR', 'Butterworth'};
            app.FilterTypeDropDown.Value = 'FIR';
            app.ApplyFilterButton = uibutton(app.BandpassFilterSettingsPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.ApplyHilbertButton = uibutton(app.BandpassFilterSettingsPanel, 'push');
            app.ApplyHilbertButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHilbertButtonPushed, true);
            app.ApplyHilbertButton.Text = 'Apply Hilbert Transform';
            app.FilteredSignalPanel = uipanel(app.FilteringPhaseTab);
            app.FilteredSignalPanel.Title = 'Filtered Signal';
            app.FilteredEEGAxes = uiaxes(app.FilteredSignalPanel);
            title(app.FilteredEEGAxes, 'Bandpass Filtered EEG')
            xlabel(app.FilteredEEGAxes, 'Time (ms)')
            ylabel(app.FilteredEEGAxes, 'Amplitude (µV)')
            zlabel(app.FilteredEEGAxes, 'Z')
            app.HilbertResultsPanel = uipanel(app.FilteringPhaseTab);
            app.HilbertResultsPanel.Title = 'Hilbert Results';
            app.HilbertAmplitudeAxes = uiaxes(app.HilbertResultsPanel);
            title(app.HilbertAmplitudeAxes, 'Instantaneous Amplitude')
            xlabel(app.HilbertAmplitudeAxes, 'Time (ms)')
            ylabel(app.HilbertAmplitudeAxes, 'Amplitude (µV)')
            zlabel(app.HilbertAmplitudeAxes, 'Z')
            app.HilbertPhaseAxes = uiaxes(app.HilbertResultsPanel);
            title(app.HilbertPhaseAxes, 'Instantaneous Phase')
            xlabel(app.HilbertPhaseAxes, 'Time (ms)')
            ylabel(app.HilbertPhaseAxes, 'Phase (rad)')
            zlabel(app.HilbertPhaseAxes, 'Z')
            app.AnalyticSignalAxes = uiaxes(app.HilbertResultsPanel);
            title(app.AnalyticSignalAxes, 'Analytic Signal')
            xlabel(app.AnalyticSignalAxes, 'Time (ms)')
            ylabel(app.AnalyticSignalAxes, 'Amplitude (µV)')
            zlabel(app.AnalyticSignalAxes, 'Z')
            app.InstantaneousFrequencyAxes = uiaxes(app.HilbertResultsPanel);
            title(app.InstantaneousFrequencyAxes, 'Instantaneous Frequency')
            xlabel(app.InstantaneousFrequencyAxes, 'Time (ms)')
            ylabel(app.InstantaneousFrequencyAxes, 'Frequency (Hz)')
            zlabel(app.InstantaneousFrequencyAxes, 'Z')
            app.STFTAnalysisTab = uitab(app.TabGroup);
            app.STFTAnalysisTab.Title = 'STFT Analysis';
            app.STFTSettingsPanel = uipanel(app.STFTAnalysisTab);
            app.STFTSettingsPanel.Title = 'STFT Settings';
            app.WindowTypeDropDownLabel = uilabel(app.STFTSettingsPanel);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.STFTWindowDropDown = uidropdown(app.STFTSettingsPanel);
            app.STFTWindowDropDown.Items = {'Hann', 'Hamming', 'Gaussian'};
            app.STFTWindowDropDown.Value = 'Hann';
            app.WindowlengthmsEditFieldLabel = uilabel(app.STFTSettingsPanel);
            app.WindowlengthmsEditFieldLabel.Text = 'Window length (ms)';
            app.STFTWindowLengthField = uieditfield(app.STFTSettingsPanel, 'numeric');
            app.STFTWindowLengthField.Value = 500;
            app.OverlapEditFieldLabel = uilabel(app.STFTSettingsPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap (%)';
            app.STFTOverlapField = uieditfield(app.STFTSettingsPanel, 'numeric');
            app.STFTOverlapField.Value = 50;
            app.MinFrequencyHzEditFieldLabel = uilabel(app.STFTSettingsPanel);
            app.MinFrequencyHzEditFieldLabel.Text = 'Min Frequency (Hz)';
            app.STFTMinFrequencyField = uieditfield(app.STFTSettingsPanel, 'numeric');
            app.STFTMinFrequencyField.Value = 1;
            app.MaxFrequencyHzEditFieldLabel = uilabel(app.STFTSettingsPanel);
            app.MaxFrequencyHzEditFieldLabel.Text = 'Max Frequency (Hz)';
            app.STFTMaxFrequencyField = uieditfield(app.STFTSettingsPanel, 'numeric');
            app.STFTMaxFrequencyField.Value = 40;
            app.RunSTFTButton = uibutton(app.STFTSettingsPanel, 'push');
            app.RunSTFTButton.ButtonPushedFcn = createCallbackFcn(app, @RunSTFTButtonPushed, true);
            app.RunSTFTButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'play.png');
            app.RunSTFTButton.Text = 'Run STFT';
            app.STFTResultsPanel = uipanel(app.STFTAnalysisTab);
            app.STFTResultsPanel.Title = 'STFT Results';
            app.STFTSpectrumAxes = uiaxes(app.STFTResultsPanel);
            title(app.STFTSpectrumAxes, 'Power Spectrum')
            xlabel(app.STFTSpectrumAxes, 'Frequency (Hz)')
            ylabel(app.STFTSpectrumAxes, 'Power')
            zlabel(app.STFTSpectrumAxes, 'Z')
            app.STFTTimeFrequencyAxes = uiaxes(app.STFTResultsPanel);
            title(app.STFTTimeFrequencyAxes, 'Time-Frequncy Representation')
            xlabel(app.STFTTimeFrequencyAxes, 'Time (ms)')
            ylabel(app.STFTTimeFrequencyAxes, 'Frequency (Hz)')
            zlabel(app.STFTTimeFrequencyAxes, 'Z')
            app.WindowComparisonPanel = uipanel(app.STFTAnalysisTab);
            app.WindowComparisonPanel.Title = 'Window Comparison';
            app.WindowComparisonAxes = uiaxes(app.WindowComparisonPanel);
            title(app.WindowComparisonAxes, 'Window Functions')
            xlabel(app.WindowComparisonAxes, 'Samples')
            ylabel(app.WindowComparisonAxes, 'Amplitude')
            zlabel(app.WindowComparisonAxes, 'Z')
            app.CompareWindowsButton = uibutton(app.WindowComparisonPanel, 'push');
            app.CompareWindowsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareWindowsButtonPushed, true);
            app.CompareWindowsButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'decision.png');
            app.CompareWindowsButton.Text = 'Compare Windows';
            app.MultitaperAnalysisTab = uitab(app.TabGroup);
            app.MultitaperAnalysisTab.Title = 'Multitaper Analysis';
            app.MultitaperSettingsPanel = uipanel(app.MultitaperAnalysisTab);
            app.MultitaperSettingsPanel.Title = 'Multitaper Settings';
            app.WindowLengthmsEditFieldLabel = uilabel(app.MultitaperSettingsPanel);
            app.WindowLengthmsEditFieldLabel.Text = 'Window Length (ms)';
            app.MTWindowLengthField = uieditfield(app.MultitaperSettingsPanel, 'numeric');
            app.MTWindowLengthField.Value = 500;
            app.EditField2Label = uilabel(app.MultitaperSettingsPanel);
            app.EditField2Label.Text = 'Time-Bandwidth Product';
            app.TimeBandwidthField = uieditfield(app.MultitaperSettingsPanel, 'numeric');
            app.TimeBandwidthField.Value = 3;
            app.NumberofTapersEditFieldLabel = uilabel(app.MultitaperSettingsPanel);
            app.NumberofTapersEditFieldLabel.Text = 'Number of Tapers';
            app.NumberTapersField = uieditfield(app.MultitaperSettingsPanel, 'numeric');
            app.NumberTapersField.Value = 5;
            app.OverlapEditFieldLabel_2 = uilabel(app.MultitaperSettingsPanel);
            app.OverlapEditFieldLabel_2.Text = 'Overlap (%)';
            app.MTOverlapField = uieditfield(app.MultitaperSettingsPanel, 'numeric');
            app.MTOverlapField.Value = 50;
            app.MinFrequencyHzEditFieldLabel_2 = uilabel(app.MultitaperSettingsPanel);
            app.MinFrequencyHzEditFieldLabel_2.Text = 'Min Frequency (Hz)';
            app.MTMinFrequencyField = uieditfield(app.MultitaperSettingsPanel, 'numeric');
            app.MTMinFrequencyField.Value = 1;
            app.MaxFrequencyHzEditFieldLabel_2 = uilabel(app.MultitaperSettingsPanel);
            app.MaxFrequencyHzEditFieldLabel_2.Text = 'Max Frequency (Hz)';
            app.MTMaxFrequencyField = uieditfield(app.MultitaperSettingsPanel, 'numeric');
            app.MTMaxFrequencyField.Value = 40;
            app.RunMultitaperButton = uibutton(app.MultitaperSettingsPanel, 'push');
            app.RunMultitaperButton.ButtonPushedFcn = createCallbackFcn(app, @RunMultitaperButtonPushed, true);
            app.RunMultitaperButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'play.png');
            app.RunMultitaperButton.Text = 'Run Multitaper';
            app.MultitaperResultsPanel = uipanel(app.MultitaperAnalysisTab);
            app.MultitaperResultsPanel.Title = 'Multitaper Results';
            app.DPSSTapersAxes = uiaxes(app.MultitaperResultsPanel);
            title(app.DPSSTapersAxes, 'DPSS Tapers')
            xlabel(app.DPSSTapersAxes, 'Samples')
            ylabel(app.DPSSTapersAxes, 'Amplitude')
            zlabel(app.DPSSTapersAxes, 'Z')
            app.MultitaperSpectrumAxes = uiaxes(app.MultitaperResultsPanel);
            title(app.MultitaperSpectrumAxes, 'Multitaper Power Spectrum')
            xlabel(app.MultitaperSpectrumAxes, 'Frequency (Hz)')
            ylabel(app.MultitaperSpectrumAxes, 'Power')
            zlabel(app.MultitaperSpectrumAxes, 'Z')
            app.MultitaperTimeFrequencyAxes = uiaxes(app.MultitaperResultsPanel);
            title(app.MultitaperTimeFrequencyAxes, 'Multitaper Time-Frequency Power')
            xlabel(app.MultitaperTimeFrequencyAxes, 'Time (ms)')
            ylabel(app.MultitaperTimeFrequencyAxes, 'Frequency (Hz)')
            zlabel(app.MultitaperTimeFrequencyAxes, 'Z')
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.ComparisonSettingsPanel = uipanel(app.ComparisonTab);
            app.ComparisonSettingsPanel.Title = 'Comparison Settings';
            app.ComparisonMetricDropDownLabel = uilabel(app.ComparisonSettingsPanel);
            app.ComparisonMetricDropDownLabel.Text = 'Comparison Metric';
            app.ComparisonMetricDropDown = uidropdown(app.ComparisonSettingsPanel);
            app.ComparisonMetricDropDown.Items = {'Power', 'Peak Frequency', 'Computation Time'};
            app.ComparisonMetricDropDown.Value = 'Power';
            app.CompareMethodsButton = uibutton(app.ComparisonSettingsPanel, 'push');
            app.CompareMethodsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareMethodsButtonPushed, true);
            app.CompareMethodsButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'decision.png');
            app.CompareMethodsButton.Text = 'Compare Methods';
            app.ComparisonPlotsPanel = uipanel(app.ComparisonTab);
            app.ComparisonPlotsPanel.Title = 'Comparison Plots';
            app.HilbertComparisonAxes = uiaxes(app.ComparisonPlotsPanel);
            title(app.HilbertComparisonAxes, 'Filter-Hilbert Result')
            xlabel(app.HilbertComparisonAxes, 'X')
            ylabel(app.HilbertComparisonAxes, 'Y')
            zlabel(app.HilbertComparisonAxes, 'Z')
            app.STFTComparisonAxes = uiaxes(app.ComparisonPlotsPanel);
            title(app.STFTComparisonAxes, 'STFT Result')
            xlabel(app.STFTComparisonAxes, 'X')
            ylabel(app.STFTComparisonAxes, 'Y')
            zlabel(app.STFTComparisonAxes, 'Z')
            app.MultitaperComparisonAxes = uiaxes(app.ComparisonPlotsPanel);
            title(app.MultitaperComparisonAxes, 'Multitaper Result')
            xlabel(app.MultitaperComparisonAxes, 'X')
            ylabel(app.MultitaperComparisonAxes, 'Y')
            zlabel(app.MultitaperComparisonAxes, 'Z')
            app.SpectralComparisonAxes = uiaxes(app.ComparisonPlotsPanel);
            title(app.SpectralComparisonAxes, 'STFT vs Multitaper spectrum')
            xlabel(app.SpectralComparisonAxes, 'Frequency (Hz)')
            ylabel(app.SpectralComparisonAxes, 'Normalized Power')
            zlabel(app.SpectralComparisonAxes, 'Z')
            app.ComparisonSummaryPanel = uipanel(app.ComparisonTab);
            app.ComparisonSummaryPanel.Title = 'Comparison Summary';
            app.ComparisonTable = uitable(app.ComparisonSummaryPanel);
            app.ComparisonTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ComparisonTable.RowName = {};
            app.ResultExportTab = uitab(app.TabGroup);
            app.ResultExportTab.Title = 'Result & Export';
            app.AnalysisSummaryPanel = uipanel(app.ResultExportTab);
            app.AnalysisSummaryPanel.Title = 'Analysis Summary';
            app.ChannelCardPanel = uipanel(app.AnalysisSummaryPanel);
            app.ResultChannelValueLabel = uilabel(app.ChannelCardPanel);
            app.ResultChannelValueLabel.Text = '--';
            app.SelectedChannelLabel = uilabel(app.ChannelCardPanel);
            app.SelectedChannelLabel.Text = 'Selected Channel';
            app.FilterBandCardPanel = uipanel(app.AnalysisSummaryPanel);
            app.FilterBandLabel = uilabel(app.FilterBandCardPanel);
            app.FilterBandLabel.Text = 'Filter Band';
            app.ResultFilterBandValueLabel = uilabel(app.FilterBandCardPanel);
            app.ResultFilterBandValueLabel.Text = '--';
            app.TrialCardPanel = uipanel(app.AnalysisSummaryPanel);
            app.SelectedTrialLabel = uilabel(app.TrialCardPanel);
            app.SelectedTrialLabel.Text = 'Selected Trial';
            app.ResultTrialValueLabel = uilabel(app.TrialCardPanel);
            app.ResultTrialValueLabel.Text = '--';
            app.STFTWindowCardPanel = uipanel(app.AnalysisSummaryPanel);
            app.STFTWindowLabel = uilabel(app.STFTWindowCardPanel);
            app.STFTWindowLabel.Text = 'STFT Window';
            app.ResultSTFTWindowValueLabel = uilabel(app.STFTWindowCardPanel);
            app.ResultSTFTWindowValueLabel.Text = '--';
            app.NWCardPanel = uipanel(app.AnalysisSummaryPanel);
            app.MultitaperNWLabel = uilabel(app.NWCardPanel);
            app.MultitaperNWLabel.Text = 'Multitaper NW';
            app.ResultNWValueLabel = uilabel(app.NWCardPanel);
            app.ResultNWValueLabel.Text = '--';
            app.SamplingRateCardPanel = uipanel(app.AnalysisSummaryPanel);
            app.SamplingRateLabel_2 = uilabel(app.SamplingRateCardPanel);
            app.SamplingRateLabel_2.Text = 'Sampling Rate';
            app.ResultSamplingRateValueLabel = uilabel(app.SamplingRateCardPanel);
            app.ResultSamplingRateValueLabel.Text = '--';
            app.TaperCardPanel = uipanel(app.AnalysisSummaryPanel);
            app.NumberofTapersLabel = uilabel(app.TaperCardPanel);
            app.NumberofTapersLabel.Text = 'Number of Tapers';
            app.ResultTapersValueLabel = uilabel(app.TaperCardPanel);
            app.ResultTapersValueLabel.Text = '--';
            app.ResultsTablePanel = uipanel(app.ResultExportTab);
            app.ResultsTablePanel.Title = 'Results Table ';
            app.ResultsSummaryTable = uitable(app.ResultsTablePanel);
            app.ResultsSummaryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultsSummaryTable.RowName = {};
            app.ExportOptionsPanel = uipanel(app.ResultExportTab);
            app.ExportOptionsPanel.Title = 'Export Options';
            app.UpdateResultsButton = uibutton(app.ExportOptionsPanel, 'push');
            app.UpdateResultsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateResultsButtonPushed, true);
            app.UpdateResultsButton.Icon = fullfile(pathToMLAPP, 'GUI ICONS', 'data-processing.png');
            app.UpdateResultsButton.Text = 'Update Results Summary';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HelpPanel = uipanel(app.HelpTab);
            app.HelpPanel.Title = 'EEG Signal Peocessing Simulator - Help';
            app.HelpTextArea = uitextarea(app.HelpPanel);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'EEG SIGNAL PROCESSING SIMULATOR'; ''; 'HOW TO USE THE APPLICATION'; ''; '1. Load EEG Data'; 'Click "Load EEG" and select a MATLAB .mat EEG dataset.'; ''; '2. Select Data'; 'On the Home tab, choose the EEG channel and trial to analyse.'; 'Click "Plot EEG" to view the raw EEG signal.'; ''; '3. Filtering & Phase'; 'Select the lower and upper cutoff frequencies.'; 'Choose FIR or Butterworth filtering.'; 'Click "Apply Filter" to obtain the bandpass-filtered EEG.'; 'Click "Apply Hilbert Transform" to calculate:'; '- Instantaneous amplitude'; '- Instantaneous phase'; '- Instantaneous frequency'; '- Analytic signal'; ''; '4. STFT Analysis'; 'Choose the window type:'; '- Hann'; '- Hamming'; '- Gaussian'; ''; 'Set the window length, overlap and frequency range.'; 'Click "Run STFT" to generate:'; '- EEG signal'; '- Power spectrum'; '- Time-frequency representation'; ''; 'Use "Compare Windows" to compare the taper shapes.'; ''; '5. Multitaper Analysis'; 'Set:'; '- Window length'; '- Time-bandwidth product'; '- Number of DPSS tapers'; '- Overlap'; '- Frequency range'; ''; 'Click "Run Multitaper" to generate:'; '- DPSS tapers'; '- Multitaper power spectrum'; '- Multitaper time-frequency representation'; ''; '6. Comparison'; 'After completing Filter-Hilbert, STFT and Multitaper analyses,'; 'click "Compare Methods".'; ''; 'The Comparison tab displays:'; '- Filter-Hilbert amplitude'; '- STFT spectrum'; '- Multitaper spectrum'; '- Normalized STFT vs Multitaper comparison'; '- Peak frequencies'; '- Computation times'; ''; '7. Results & Export'; 'Use "Update Results Summary" to display the current analysis settings'; 'and results.'; ''; 'Use "Save Result" to save numerical results as a MATLAB .mat file.'; ''; 'Use "Export Result" to export the current application view.'; ''; '8. Reset'; 'Click "Reset" to clear the current dataset and all analysis results.'; ''; ''; 'ANALYSIS METHODS'; ''; 'Filter-Hilbert:'; 'Bandpass filtering isolates a selected EEG frequency band.'; 'The Hilbert transform provides instantaneous amplitude, phase'; 'and frequency information.'; ''; 'STFT:'; 'The Short-Time Fourier Transform divides the EEG into overlapping'; 'time windows and calculates the frequency spectrum within each window,'; 'providing a time-frequency representation.'; ''; 'Multitaper:'; 'The multitaper method applies multiple DPSS tapers to each EEG segment.'; 'The resulting spectra are averaged to improve spectral stability'; 'and reduce variance compared with a single-window spectral estimate.'};
            app.Image = uiimage(app.HelpPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI ICONS', 'eeg.png');
            app.StatusPanel = uipanel(app.GridLayout);
            app.StatusPanel.Title = 'Status';
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Status : Ready - Load an EEG dataset to begin.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end